classdef HealthDiagnosticsCenter816010063 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HealthDiagnosticsCenterUIFigure  matlab.ui.Figure
        Image                       matlab.ui.control.Image
        LoadyourdataPanel           matlab.ui.container.Panel
        LoadDataButton              matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        SelectafilePanel            matlab.ui.container.Panel
        FileSelectedEditField       matlab.ui.control.EditField
        FileSelectedEditFieldLabel  matlab.ui.control.Label
        SelectFileButton            matlab.ui.control.Button
        PlotDataButton              matlab.ui.control.Button
        DataUploadLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        %Struct to hold data extracted from Excel
        data;

        %Error flag for bad data
        errorFlag = 0;
    end
    
    properties (Access = public)
        % make table data, t, a public property - allows it to be called in
        % plotter app
        t = 0
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disp("Tariq = happy potato for getting this to (mostly) work");
        end

        % Button pushed function: PlotDataButton
        function HealthDiagnosticsCentrePlotGenerator816010063(app, event)
            try
                %Extract columns from loaded table and place into 'data' struct
                app.data.currentTime = app.t.Time_24HourClock_(:);
                %Convert time from XX:XX to hour only, for plotting
                DateVector = datevec(app.data.currentTime);
                app.data.hourData = DateVector(:,4);
                %Place rest of Excel data into 'data' struct
                app.data.patientWaiting = app.t.NoOfPatientsWaiting(:);
                app.data.pendingTests = app.t.NoOfTestsPending(:);
                app.data.availTechs = app.t.NoOfTechniciansAvailable(:);
                app.data.techsPerPatient = app.t.NoOfTechniciansPerPatient(:);
                app.data.techsPerTest = app.t.NoOfTechniciansPerTest(:);
                
                %Launch plotter app with 'data' struct as startup inputs
                if app.errorFlag == 1
                    uialert(app.HealthDiagnosticsCenterUIFigure,'Invalid Data, please check for errors in the uploaded file','Import error');
                else 
                    HealthDiagnosticsCenterPlotGenerator816010063(app.data);
                end
            catch
                uialert(app.HealthDiagnosticsCenterUIFigure,'Invalid Data, please check for errors in the uploaded file','Import error');
            end
        end

        % Close request function: HealthDiagnosticsCenterUIFigure
        function HealthDiagnosticsCenterUIFigureCloseRequest(app, event)
        % Create dialog box and ask for user input
         selection = uiconfirm(app.HealthDiagnosticsCenterUIFigure,...
         'Are you sure you want to leave?',...
         'Confirm Close','Options',{'Yes','Cancel'},...
         'Icon','question');
        
         % Make decision based on user selection
         switch selection
         case 'Yes'
            delete(app.HealthDiagnosticsCenterUIFigure) % Close dialog box
         case 'Cancel'
         % Do nothing
         end

        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            %Error handling for incorrect file path
            try
                %Importing data from excel -> MATLAB Table
                app.t = readtable(app.FileSelectedEditField.Value);
                %Converting time to actual time 
                app.t.Time_24HourClock_= datetime(app.t.Time_24HourClock_,'ConvertFrom','excel', 'Format','HH:mm');
                %Display table in CMD for good luck
    %             disp(app.t)
                %Populate table
                app.UITable.Data = app.t;
                %Set column names in case default fails
                app.UITable.ColumnName = {'Time (24 hour clock)','No of patients waiting','No of tests pending', 'No of technicians available', 'No of technicians per patient', 'No of technicians per test'};
    
                tableNumValues = cellfun(@isnan,table2cell(app.t(:,2:6)));
                tableDateValues = cellfun(@isdatetime,table2cell(app.t(:,1)));
                if (numel(tableNumValues(tableNumValues == 1)) > 0) || (numel(tableDateValues(tableDateValues == 0)) > 0)
                    disp("Error: Data contains invalid data")
                    app.errorFlag = 1;
                    uialert(app.HealthDiagnosticsCenterUIFigure,'Invalid Data, please check for errors in the uploaded file','Import error');
                else
                    disp("Data good")
                    app.errorFlag = 0;
                end
            catch
                uialert(app.HealthDiagnosticsCenterUIFigure,'Invalid file path chosen','Import error');
            end
            
           
        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            %Create fake figure to prevent minimizaiton of main app
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            %Allow selection of excel file
            [file,path] = uigetfile('*.xlsx');
            %Remove fake figure
            delete(f); 
            %Display path of selected file
            app.FileSelectedEditField.Value = strcat(path, file);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HealthDiagnosticsCenterUIFigure = uifigure('Visible', 'off');
            app.HealthDiagnosticsCenterUIFigure.Name = 'Health Diagnostics Center';
            app.HealthDiagnosticsCenterUIFigure.Icon = 'cdc-k0KRNtqcjfw-unsplash-removebg-preview 1.png';
            app.HealthDiagnosticsCenterUIFigure.CloseRequestFcn = createCallbackFcn(app, @HealthDiagnosticsCenterUIFigureCloseRequest, true);
            app.DataUploadLabel = uilabel(app.HealthDiagnosticsCenterUIFigure);
            app.DataUploadLabel.Text = 'Data Upload';
            app.PlotDataButton = uibutton(app.HealthDiagnosticsCenterUIFigure, 'push');
            app.PlotDataButton.ButtonPushedFcn = createCallbackFcn(app, @HealthDiagnosticsCentrePlotGenerator816010063, true);
            app.PlotDataButton.Text = 'Plot Data';
            app.SelectafilePanel = uipanel(app.HealthDiagnosticsCenterUIFigure);
            app.SelectafilePanel.Title = 'Select a file';
            app.SelectFileButton = uibutton(app.SelectafilePanel, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.FileSelectedEditFieldLabel = uilabel(app.SelectafilePanel);
            app.FileSelectedEditFieldLabel.Text = 'File Selected';
            app.FileSelectedEditField = uieditfield(app.SelectafilePanel, 'text');
            app.FileSelectedEditField.Editable = 'off';
            app.FileSelectedEditField.Placeholder = 'C:/user/path';
            app.LoadyourdataPanel = uipanel(app.HealthDiagnosticsCenterUIFigure);
            app.LoadyourdataPanel.Title = 'Load your data';
            app.UITable = uitable(app.LoadyourdataPanel);
            app.UITable.ColumnName = {'Time (24 hour clock)'; 'No of patients waiting'; 'No of tests pending'; 'No of technicians available'; 'No of technicians per patient'; 'No of technicians per test'};
            app.UITable.RowName = {};
            app.LoadDataButton = uibutton(app.LoadyourdataPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Tooltip = {'Click to load your file!'};
            app.LoadDataButton.Text = 'Load Data';
            app.Image = uiimage(app.HealthDiagnosticsCenterUIFigure);
            app.Image.ImageSource = 'cdc-k0KRNtqcjfw-unsplash-removebg-preview 1.png';
            % Show the figure after all components are created
            app.HealthDiagnosticsCenterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HealthDiagnosticsCenter816010063

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HealthDiagnosticsCenterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HealthDiagnosticsCenterUIFigure)
        end
    end
end