classdef HealthDiagnosticsCenterPlotGenerator816010063 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HealthDiagnosticsCenterUIFigure  matlab.ui.Figure
        Image                      matlab.ui.control.Image
        ShowGridCheckBox           matlab.ui.control.CheckBox
        ColourSchemesListBox       matlab.ui.control.ListBox
        ColourSchemesListBoxLabel  matlab.ui.control.Label
        AxesSelectorPanel          matlab.ui.container.Panel
        ZDropDown                  matlab.ui.control.DropDown
        ZDropDownLabel             matlab.ui.control.Label
        YDropDown                  matlab.ui.control.DropDown
        YDropDownLabel             matlab.ui.control.Label
        XDropDown                  matlab.ui.control.DropDown
        XDropDownLabel             matlab.ui.control.Label
        PlotTypeButtonGroup        matlab.ui.container.ButtonGroup
        DButton_2                  matlab.ui.control.RadioButton
        DButton                    matlab.ui.control.RadioButton
        PlotGeneratorLabel         matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
            %Initialize data to be imported from other app
            data;

            %Initialize properties to be used for changing axes data
            inputX;
            inputY;
            inputZ;

            %Initialize properties for new dropdown value
            newXDropDownValue;
            newYDropDownValue;
            newZDropDownValue;

            %Initialize property for line colour
            line;

    end
    
    methods (Access = private)
        
        function plot3D(app, xIn, yIn, zIn, xDrop, yDrop, zDrop)
            %Assign x, y & z variables to the function inputs
            x = xIn;
    	    y = yIn;
    	    z = zIn;
            
            %Develop 3D grid data
            xLin = linspace(min(x), max(x), 20);
    	    yLin = linspace(min(y), max(y), 20);
    	    [X,Y] = meshgrid(xLin,yLin);
    	    Z = griddata(x, y, z, X, Y);
            disp(griddata(x, y, z, X, Y))
            %Test outputs
    	    disp("---------------")
            disp('X')
            disp(X)
            disp("Y")
            disp(Y)
            disp("Z")
            disp(Z)


            %3D plot
    	    surf(app.UIAxes, X, Y ,Z);

    	    %Config axes names & title
    	    app.UIAxes.Title.String = '';
    	    app.UIAxes.XLabel.String = xDrop;
    	    app.UIAxes.YLabel.String = yDrop;
    	    app.UIAxes.ZLabel.String = zDrop;

            %Grid showing
            checkBoxValue = app.ShowGridCheckBox.Value;
            %Check value of checkboxes
            switch checkBoxValue
                %Case 1: Checkbox on (show grid)
                case 1
                    app.UIAxes.XGrid = 'on';
                    app.UIAxes.YGrid = 'on';
                    %Is plot a 3d plot?
                    if app.DButton_2.Value == 1
                        app.UIAxes.ZGrid = 'on';
                    else
                        app.UIAxes.ZGrid = 'off';
                    end 
                %Case 2: Checkbox off (hide grid)
                case 0 
                    app.UIAxes.XGrid = 'off';
                    app.UIAxes.YGrid = 'off';
                    app.UIAxes.ZGrid = 'off';
            end
        end


        function plot2D(app, xIn, yIn, xDrop, yDrop)
            %Assign x and y variables to the function inputs
            x = xIn;
            y = yIn;
            
            %2D plot
            plot(app.UIAxes,x,y);
    	    
            %Config axes names & title
    	    app.UIAxes.Title.String = '';
    	    app.UIAxes.XLabel.String = xDrop;
    	    app.UIAxes.YLabel.String = yDrop;

            %Grid showing
            checkBoxValue = app.ShowGridCheckBox.Value;
            %Check value of checkboxes
            switch checkBoxValue
                %Case 1: Checkbox on (show grid)
                case 1
                    app.UIAxes.XGrid = 'on';
                    app.UIAxes.YGrid = 'on';
                    %Is plot a 3d plot?
                    if app.DButton_2.Value == 1
                        app.UIAxes.ZGrid = 'on';
                    else
                        app.UIAxes.ZGrid = 'off';
                    end 
                %Case 2: Checkbox off (hide grid)
                case 0 
                    app.UIAxes.XGrid = 'off';
                    app.UIAxes.YGrid = 'off';
                    app.UIAxes.ZGrid = 'off';
            end
            
        end

        function plotColour(app)
            colourSchemeValue = app.ColourSchemesListBox.Value;
            if (app.DButton.Value == 1) && (app.DButton_2.Value == 0)
                plotChildren = app.UIAxes.Children;
                app.line = plotChildren(1);
                switch colourSchemeValue
                    case 'Red'
                        app.line.Color = 'r';
                    case 'Blue'
                        app.line.Color = 'b';
                    case 'Green'
                        app.line.Color = 'g';
                end
            else
                switch colourSchemeValue
                    %3D colour switch
                    case 'Parula'
                        app.UIAxes.Colormap = parula;
                    case 'Summer'
                        app.UIAxes.Colormap = summer;
                    case 'Winter'
                        app.UIAxes.Colormap = winter;    
                end
            end 
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data)
           
            %Import data
            app.data = data;

            %Initialize first plot as a 3D plot
            app.inputX = app.data.hourData;
            app.inputY = app.data.patientWaiting;
            app.inputZ = app.data.pendingTests;

            x = app.inputX;
	        y = app.inputY;
	        z = app.inputZ;

            xLin = linspace(min(x), max(x), 20);
            yLin = linspace(min(y), max(y), 20);
            [X,Y] = meshgrid(xLin,yLin);
            Z = griddata(x, y, z, X, Y);
            %Confirm that Z renders
            disp(Z)
            %3D plot
            surf(app.UIAxes, X, Y, Z);
            %Config axes names & title
            app.UIAxes.Title.String = '';
            app.UIAxes.XLabel.String = 'hourData';
            app.UIAxes.YLabel.String = 'patientWaiting';
            app.UIAxes.ZLabel.String = 'pendingTests';
            %Set default colour to parula
            app.UIAxes.Colormap = parula;
            
            %Initialize dropdown variable names
            app.XDropDown.Items = {'hourData', 'patientWaiting', 'pendingTests', 'availTechs', 'techsPerPatient', 'techsPerTest'};
            app.YDropDown.Items = {'hourData', 'patientWaiting', 'pendingTests', 'availTechs', 'techsPerPatient', 'techsPerTest'};
            app.ZDropDown.Items = {'hourData', 'patientWaiting', 'pendingTests', 'availTechs', 'techsPerPatient', 'techsPerTest'};
            
            app.XDropDown.Value = 'hourData';
            app.YDropDown.Value = 'patientWaiting';
            app.ZDropDown.Value = 'pendingTests';

            %Enable Z dropdown on initilization
            app.ZDropDown.Enable = 1;

            %Initialize colourpicker
            %Colourpicker items
            app.ColourSchemesListBox.Items = {'Parula', 'Winter','Summer',};
            %Colourpicker value
            app.ColourSchemesListBox.Value = 'Parula';

            %Initialize grid as on
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';

        end

        % Close request function: HealthDiagnosticsCenterUIFigure
        function HealthDiagnosticsCenterUIFigureCloseRequest(app, event)
            % Create dialog box and ask for user input
            selection = uiconfirm(app.HealthDiagnosticsCenterUIFigure,...
            'Are you sure you want to leave?',...
            'Confirm Close','Options',{'Yes','Cancel'},...
            'Icon','question');
            
            % Make decision based on user selection
            switch selection
            case 'Yes'
                delete(app.HealthDiagnosticsCenterUIFigure) % Close dialog box
            case 'Cancel'
                % Do nothing
            end

        end

        % Selection changed function: PlotTypeButtonGroup
        function PlotTypeButtonGroupSelectionChanged(app, event)
            %Switching between 2D and 3D plots
            selectedButton = app.PlotTypeButtonGroup.SelectedObject;
            switch selectedButton

                %Case: 2D selected
                case app.DButton
                    %Show that we are in 2D mode
%                     disp('2D selected.')
                    %Reset plot
                    reset(app.UIAxes)
                    %Disable Z axis configuration
                    app.ZDropDown.Enable = 0;
                    %Configure colourpicker options for 2D plot
                    app.ColourSchemesListBox.Items = {'Red', 'Blue','Green',};
                    %Set colourpicker default value of red
                    app.ColourSchemesListBox.Value = 'Red';
                    %Show 2D plot for xy
                    plot2D(app, app.inputX, app.inputY, app.XDropDown.Value, app.YDropDown.Value);
                    %Configure initial plot colour as red
                    plotChildren = app.UIAxes.Children;
                    app.line = plotChildren(1);
                    app.line.Color = 'r';

                %Case: 3D selected
                case app.DButton_2
                    %Show that we are in 3D mode
%                     disp('3D selected.')
                    %Reset plot
                    reset(app.UIAxes);
                    %Enable the Z axis configuration
                    app.ZDropDown.Enable = 1;
                    %Colourpicker items for 3D plot
                    app.ColourSchemesListBox.Items = {'Parula', 'Winter','Summer',};
                    %Colourpicker value of parula
                    app.ColourSchemesListBox.Value = 'Parula';
                    %Set default colour to parula
                    app.UIAxes.Colormap = parula;
                    %Show 3D plot for xyz
                    plot3D(app, app.inputX, app.inputY, app.inputZ, app.XDropDown.Value, app.YDropDown.Value, app.ZDropDown.Value);

             end
        end

        % Value changed function: ShowGridCheckBox
        function ShowGridCheckBoxValueChanged(app, event)
            %instantiate checkbox value
            checkBoxValue = app.ShowGridCheckBox.Value;
            %Check value of checkboxes
            switch checkBoxValue
                %Case 1: Checkbox on (show grid)
                case 1
%                     disp("grid on")
                    app.UIAxes.XGrid = 'on';
                    app.UIAxes.YGrid = 'on';
                    %Is plot a 3d plot?
                    if app.DButton_2.Value == 1
                        app.UIAxes.ZGrid = 'on';
                    else
                        app.UIAxes.ZGrid = 'off';
                    end 
                %Case 2: Checkbox off (hide grid)
                case 0 
%                     disp("grid off")
                    app.UIAxes.XGrid = 'off';
                    app.UIAxes.YGrid = 'off';
                    app.UIAxes.ZGrid = 'off';
            end
        end

        % Value changed function: XDropDown
        function XDropDownValueChangedFcn(app, event)
            app.newXDropDownValue = app.XDropDown.Value;
            graphType2D = app.DButton.Value;
            graphType3D = app.DButton_2.Value;
%             disp(app.newXDropDownValue)
%             disp(app.YDropDown.Value)

            if (strcmp(app.newXDropDownValue,app.YDropDown.Value)) || (strcmp(app.newXDropDownValue, app.ZDropDown.Value))
                uialert(app.HealthDiagnosticsCenterUIFigure,'Redundant axes. Please change variable. Be sure to check whether your z axes and x are the same, in the case of 2D plots.','Plot Error');
            else
                %2D plot
                if (graphType2D == 1) && (graphType3D ==0) 
                    disp("2D yayy")
                    switch app.newXDropDownValue 
                        case 'hourData'
                            app.inputX = app.data.hourData;
                            plot2D(app, app.inputX, app.inputY, app.XDropDown.Value, app.YDropDown.Value);
                            plotColour(app)
                        case 'patientWaiting'
                            app.inputX = app.data.patientWaiting;
                            plot2D(app, app.inputX, app.inputY, app.XDropDown.Value, app.YDropDown.Value);
                            plotColour(app)
                        case 'pendingTests'
                            app.inputX = app.data.pendingTests;
                            plot2D(app, app.inputX, app.inputY, app.XDropDown.Value, app.YDropDown.Value);
                            plotColour(app)
                        case 'availTechs'
                            app.inputX = app.data.availTechs;
                            plot2D(app, app.inputX, app.inputY, app.XDropDown.Value, app.YDropDown.Value);
                            plotColour(app)
                        case 'techsPerPatient'
                            app.inputX = app.data.techsPerPatient;
                            plot2D(app, app.inputX, app.inputY, app.XDropDown.Value, app.YDropDown.Value);
                            plotColour(app)
                        case 'techsPerTest'
                            app.inputX = app.data.techsPerTest;
                            plot2D(app, app.inputX, app.inputY, app.XDropDown.Value, app.YDropDown.Value);
                            plotColour(app)
                    end
    
                %3D plot
                else
                    disp("3D yayy")
                    switch app.newXDropDownValue 
                        case 'hourData'
                            app.inputX = app.data.hourData;
                            plot3D(app, app.inputX, app.inputY, app.inputZ, app.newXDropDownValue, app.YDropDown.Value, app.ZDropDown.Value);
                            plotColour(app)
                        case 'patientWaiting'
                            uialert(app.HealthDiagnosticsCenterUIFigure,'Apologies for the error in plotting patientWaiting. Our technicians are hard at work trying to resolve the error. Please stay tuned for future releases!','Plot Error');
                            %Does not currently work
%                             app.inputX = app.data.patientWaiting;
%                             plot3D(app, app.inputX, app.inputY, app.inputZ, app.newXDropDownValue, app.YDropDown.Value, app.ZDropDown.Value);
%                             plotColour(app)
                        case 'pendingTests'
                            app.inputX = app.data.pendingTests;
                            plot3D(app, app.inputX, app.inputY, app.inputZ, app.newXDropDownValue, app.YDropDown.Value, app.ZDropDown.Value);
                            plotColour(app)
                        case 'availTechs'
                            app.inputX = app.data.availTechs;
                            plot3D(app, app.inputX, app.inputY, app.inputZ, app.newXDropDownValue, app.YDropDown.Value, app.ZDropDown.Value);
                            plotColour(app)
                        case 'techsPerPatient'
                            app.inputX = app.data.techsPerPatient;
                            plot3D(app, app.inputX, app.inputY, app.inputZ, app.newXDropDownValue, app.YDropDown.Value, app.ZDropDown.Value);
                            plotColour(app)
                        case 'techsPerTest'
                            app.inputX = app.data.techsPerTest;
                            plot3D(app, app.inputX, app.inputY, app.inputZ, app.newXDropDownValue, app.YDropDown.Value, app.ZDropDown.Value);
                            plotColour(app)
                    end
                
                end
            end
                 
        end

        % Value changed function: ColourSchemesListBox
        function ColourSchemesListBoxValueChanged(app, event)
            colourSchemeValue = app.ColourSchemesListBox.Value;
            if (app.DButton.Value == 1) && (app.DButton_2.Value == 0)
                plotChildren = app.UIAxes.Children;
                app.line = plotChildren(1);
                switch colourSchemeValue
                    case 'Red'
                        app.line.Color = 'r';
                    case 'Blue'
                        app.line.Color = 'b';
                    case 'Green'
                        app.line.Color = 'g';
                end
            else
                switch colourSchemeValue
                    %3D colour switch
                    case 'Parula'
                        app.UIAxes.Colormap = parula;
                    case 'Summer'
                        app.UIAxes.Colormap = summer;
                    case 'Winter'
                        app.UIAxes.Colormap = winter;    
                end
            end 
        end

        % Value changed function: YDropDown
        function YDropDownValueChanged(app, event)
            
            app.newYDropDownValue = app.YDropDown.Value;

            graphType2D = app.DButton.Value;
            graphType3D = app.DButton_2.Value;
            
            if (strcmp(app.newYDropDownValue,app.XDropDown.Value)) || (strcmp(app.newYDropDownValue, app.ZDropDown.Value))
                uialert(app.HealthDiagnosticsCenterUIFigure,'Redundant axes. Please change variable','Plot Error');
            else
                %2D plot
                if (graphType2D == 1) && (graphType3D ==0)
                    switch app.newYDropDownValue 
                        case 'hourData'
                            app.inputX = app.data.hourData;
                            plot2D(app, app.inputX, app.inputY, app.XDropDown.Value, app.YDropDown.Value);
                            plotColour(app)
                        case 'patientWaiting'
                            app.inputX = app.data.patientWaiting;
                            plot2D(app, app.inputX, app.inputY, app.XDropDown.Value, app.YDropDown.Value);
                            plotColour(app)
                        case 'pendingTests'
                            app.inputX = app.data.pendingTests;
                            plot2D(app, app.inputX, app.inputY, app.XDropDown.Value, app.YDropDown.Value);
                            plotColour(app)
                        case 'availTechs'
                            app.inputX = app.data.availTechs;
                            plot2D(app, app.inputX, app.inputY, app.XDropDown.Value, app.YDropDown.Value);
                            plotColour(app)
                        case 'techsPerPatient'
                            app.inputX = app.data.techsPerPatient;
                            plot2D(app, app.inputX, app.inputY, app.XDropDown.Value, app.YDropDown.Value);
                            plotColour(app)
                        case 'techsPerTest'
                            app.inputX = app.data.techsPerTest;
                            plot2D(app, app.inputX, app.inputY, app.XDropDown.Value, app.YDropDown.Value);
                            plotColour(app)
                    end
                %3D plot
                else
                    disp("3D yayy")
                    switch app.newYDropDownValue
                        case 'hourData'
                            app.inputX = app.data.hourData;
                            plot3D(app, app.inputX, app.inputY, app.inputZ, app.XDropDown.Value, app.newYDropDownValue, app.ZDropDown.Value);
                            plotColour(app)
                        case 'patientWaiting'
                            app.inputX = app.data.patientWaiting;
                            plot3D(app, app.inputX, app.inputY, app.inputZ, app.XDropDown.Value, app.newYDropDownValue, app.ZDropDown.Value);
                            plotColour(app)
                        case 'pendingTests'
                            app.inputX = app.data.pendingTests;
                            plot3D(app, app.inputX, app.inputY, app.inputZ, app.XDropDown.Value, app.newYDropDownValue, app.ZDropDown.Value);
                            plotColour(app)
                        case 'availTechs'
                            app.inputX = app.data.availTechs;
                            plot3D(app, app.inputX, app.inputY, app.inputZ, app.XDropDown.Value, app.newYDropDownValue, app.ZDropDown.Value);
                            plotColour(app)
                        case 'techsPerPatient'
                            app.inputX = app.data.techsPerPatient;
                            plot3D(app, app.inputX, app.inputY, app.inputZ, app.XDropDown.Value, app.newYDropDownValue, app.ZDropDown.Value);
                            plotColour(app)
                        case 'techsPerTest'
                            app.inputX = app.data.techsPerTest;
                            plot3D(app, app.inputX, app.inputY, app.inputZ, app.XDropDown.Value, app.newYDropDownValue, app.ZDropDown.Value);
                            plotColour(app)
                    end
                end
            end      
            
        end

        % Value changed function: ZDropDown
        function ZDropDownValueChanged(app, event)
            app.newZDropDownValue = app.ZDropDown.Value;

            graphType2D = app.DButton.Value;
            graphType3D = app.DButton_2.Value;

            if (strcmp(app.newZDropDownValue,app.XDropDown.Value)) || (strcmp(app.newZDropDownValue, app.YDropDown.Value))
                uialert(app.HealthDiagnosticsCenterUIFigure,'Redundant axes. Please change variable. Be sure to check whether your z axes and x are the same, in the case of 2D plots.','Plot Error');
            else
                %2D plot
                if (graphType2D == 1) && (graphType3D ==0)
                    %Do nothing
                    disp("2D engaged, no need for plot updates")
                %3D plot
                else
                    disp("3D yayy")
                    switch app.newZDropDownValue
                        case 'hourData'
                            app.inputX = app.data.hourData;
                            plot3D(app, app.inputX, app.inputY, app.inputZ, app.XDropDown.Value, app.YDropDown.Value, app.newZDropDownValue);
                            plotColour(app)
                        case 'patientWaiting'
                            uialert(app.HealthDiagnosticsCenterUIFigure,'Apologies for the error in plotting patientWaiting. Our technicians are hard at work trying to resolve the error. Please stay tuned for future releases!','Plot Error');
                            %Does not currently work
%                             app.inputX = app.data.patientWaiting;
%                             plot3D(app, app.inputX, app.inputY, app.inputZ, app.XDropDown.Value, app.YDropDown.Value, app.newZDropDownValue);
%                             plotColour(app)
                        case 'pendingTests'
                            app.inputX = app.data.pendingTests;
                            plot3D(app, app.inputX, app.inputY, app.inputZ, app.XDropDown.Value, app.YDropDown.Value, app.newZDropDownValue);
                            plotColour(app)
                        case 'availTechs'
                            app.inputX = app.data.availTechs;
                            plot3D(app, app.inputX, app.inputY, app.inputZ, app.XDropDown.Value, app.YDropDown.Value, app.newZDropDownValue);
                            plotColour(app)
                        case 'techsPerPatient'
                            app.inputX = app.data.techsPerPatient;
                            plot3D(app, app.inputX, app.inputY, app.inputZ, app.XDropDown.Value, app.YDropDown.Value, app.newZDropDownValue);
                            plotColour(app)
                        case 'techsPerTest'
                            app.inputX = app.data.techsPerTest;
                            plot3D(app, app.inputX, app.inputY, app.inputZ, app.XDropDown.Value, app.YDropDown.Value, app.newZDropDownValue);
                            plotColour(app)
                    end
                end
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HealthDiagnosticsCenterUIFigure = uifigure('Visible', 'off');
            app.HealthDiagnosticsCenterUIFigure.Name = 'Health Diagnostics Center';
            app.HealthDiagnosticsCenterUIFigure.Icon = 'cdc-k0KRNtqcjfw-unsplash-removebg-preview 1.png';
            app.HealthDiagnosticsCenterUIFigure.CloseRequestFcn = createCallbackFcn(app, @HealthDiagnosticsCenterUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.HealthDiagnosticsCenterUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PlotGeneratorLabel = uilabel(app.HealthDiagnosticsCenterUIFigure);
            app.PlotGeneratorLabel.Text = 'Plot Generator';
            app.PlotTypeButtonGroup = uibuttongroup(app.HealthDiagnosticsCenterUIFigure);
            app.PlotTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.PlotTypeButtonGroup.Title = 'Plot Type';
            app.DButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.DButton.Text = '2D';
            app.DButton_2 = uiradiobutton(app.PlotTypeButtonGroup);
            app.DButton_2.Text = '3D';
            app.DButton_2.Value = true;
            app.AxesSelectorPanel = uipanel(app.HealthDiagnosticsCenterUIFigure);
            app.AxesSelectorPanel.Title = 'Axes Selector';
            app.XDropDownLabel = uilabel(app.AxesSelectorPanel);
            app.XDropDownLabel.Text = 'X';
            app.XDropDown = uidropdown(app.AxesSelectorPanel);
            app.XDropDown.ValueChangedFcn = createCallbackFcn(app, @XDropDownValueChangedFcn, true);
            app.YDropDownLabel = uilabel(app.AxesSelectorPanel);
            app.YDropDownLabel.Text = 'Y';
            app.YDropDown = uidropdown(app.AxesSelectorPanel);
            app.YDropDown.ValueChangedFcn = createCallbackFcn(app, @YDropDownValueChanged, true);
            app.ZDropDownLabel = uilabel(app.AxesSelectorPanel);
            app.ZDropDownLabel.Text = 'Z';
            app.ZDropDown = uidropdown(app.AxesSelectorPanel);
            app.ZDropDown.ValueChangedFcn = createCallbackFcn(app, @ZDropDownValueChanged, true);
            app.ColourSchemesListBoxLabel = uilabel(app.HealthDiagnosticsCenterUIFigure);
            app.ColourSchemesListBoxLabel.Text = 'Colour Schemes';
            app.ColourSchemesListBox = uilistbox(app.HealthDiagnosticsCenterUIFigure);
            app.ColourSchemesListBox.Items = {};
            app.ColourSchemesListBox.ValueChangedFcn = createCallbackFcn(app, @ColourSchemesListBoxValueChanged, true);
            app.ColourSchemesListBox.Value = {};
            app.ShowGridCheckBox = uicheckbox(app.HealthDiagnosticsCenterUIFigure);
            app.ShowGridCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowGridCheckBoxValueChanged, true);
            app.ShowGridCheckBox.Text = 'Show Grid';
            app.ShowGridCheckBox.Value = true;
            app.Image = uiimage(app.HealthDiagnosticsCenterUIFigure);
            app.Image.ImageSource = 'cdc-k0KRNtqcjfw-unsplash-removebg-preview 1.png';
            % Show the figure after all components are created
            app.HealthDiagnosticsCenterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HealthDiagnosticsCenterPlotGenerator816010063(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HealthDiagnosticsCenterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HealthDiagnosticsCenterUIFigure)
        end
    end
end